classdef ImageVolumeViewer_ImagePopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                      matlab.ui.Figure
        ImageVolumeDisplaySettingsPanel  matlab.ui.container.Panel
        ShowSliceIntersectionsCheckBox  matlab.ui.control.CheckBox
        MinimumSpinnerLabel             matlab.ui.control.Label
        DisplayMinimumSpinner           matlab.ui.control.Spinner
        MaximumSpinnerLabel             matlab.ui.control.Label
        DisplayMaximumSpinner           matlab.ui.control.Spinner
        WindowSpinnerLabel              matlab.ui.control.Label
        DisplayWindowSpinner            matlab.ui.control.Spinner
        LevelSpinnerLabel               matlab.ui.control.Label
        DisplayLevelSpinner             matlab.ui.control.Spinner
        CloseButton                     matlab.ui.control.Button
        DRenderDisplaySettingsPanel     matlab.ui.container.Panel
        CardinalAxesCheckBox            matlab.ui.control.CheckBox
        ImagingPlanesCheckBox           matlab.ui.control.CheckBox
        ImageVolumeBoundsCheckBox       matlab.ui.control.CheckBox
        VolumeCoordinateAxesCheckBox    matlab.ui.control.CheckBox
        RepresentativeVoxelCheckBox     matlab.ui.control.CheckBox
        RepresentativePatientCheckBox   matlab.ui.control.CheckBox
        CardinalAxesLabelsCheckBox      matlab.ui.control.CheckBox
        ImagingPlaneLabelsCheckBox      matlab.ui.control.CheckBox
        ImagingPlaneAlignmentMarkersCheckBox  matlab.ui.control.CheckBox
        VolumeDimensionsVoxelsLabelsCheckBox  matlab.ui.control.CheckBox
        VolumeDimensionsMetricLabelsCheckBox  matlab.ui.control.CheckBox
        VolumeCoordinateAxesLabelsCheckBox  matlab.ui.control.CheckBox
        RepresentativeVoxelLabelsCheckBox  matlab.ui.control.CheckBox
        LabelsCheckBox                  matlab.ui.control.CheckBox
        ImageHistogramUIAxes            matlab.ui.control.UIAxes
    end


    properties (Access = public)
        oImageVolumeViewerController = []
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, oImageVolumeViewerController)
            oImageVolumeViewerController.ImagePopUp_startupFcn(app);
        end

        % Value changed function: ShowSliceIntersectionsCheckBox
        function ShowSliceIntersectionsCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_ShowSliceIntersectionsCheckBoxValueChanged(event);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changing function: DisplayMinimumSpinner
        function DisplayMinimumSpinnerValueChanging(app, event)
            app.oImageVolumeViewerController.ImagePopUp_DisplayMinimumSpinnerValueChanging(event, app);
        end

        % Value changing function: DisplayMaximumSpinner
        function DisplayMaximumSpinnerValueChanging(app, event)
            app.oImageVolumeViewerController.ImagePopUp_DisplayMaximumSpinnerValueChanging(event, app);
        end

        % Value changing function: DisplayWindowSpinner
        function DisplayWindowSpinnerValueChanging(app, event)
            app.oImageVolumeViewerController.ImagePopUp_DisplayWindowSpinnerValueChanging(event, app);
        end

        % Value changing function: DisplayLevelSpinner
        function DisplayLevelSpinnerValueChanging(app, event)
            app.oImageVolumeViewerController.ImagePopUp_DisplayLevelSpinnerValueChanging(event, app);
        end

        % Value changed function: CardinalAxesCheckBox
        function CardinalAxesCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_CardinalAxesCheckBoxValueChanged(event);
        end

        % Value changed function: ImagingPlanesCheckBox
        function ImagingPlanesCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_ImagingPlanesCheckBoxValueChanged(event);
        end

        % Value changed function: ImageVolumeBoundsCheckBox
        function ImageVolumeBoundsCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_ImageVolumeBoundsCheckBoxValueChanged(event);
        end

        % Value changed function: VolumeCoordinateAxesCheckBox
        function VolumeCoordinateAxesCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_VolumeCoordinateAxesCheckBoxValueChanged(event);
        end

        % Value changed function: RepresentativeVoxelCheckBox
        function RepresentativeVoxelCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_RepresentativeVoxelCheckBoxValueChanged(event);
        end

        % Value changed function: RepresentativePatientCheckBox
        function RepresentativePatientCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_RepresentativePatientCheckBoxValueChanged(event);
        end

        % Value changed function: LabelsCheckBox
        function LabelsCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_LabelsCheckBoxValueChanged(event, app);
        end

        % Value changed function: CardinalAxesLabelsCheckBox
        function CardinalAxesLabelsCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_CardinalAxesLabelsCheckBoxValueChanged(event);
        end

        % Value changed function: ImagingPlaneLabelsCheckBox
        function ImagingPlaneLabelsCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_ImagingPlaneLabelsCheckBoxValueChanged(event);
        end

        % Value changed function: 
        % ImagingPlaneAlignmentMarkersCheckBox
        function ImagingPlaneAlignmentMarkersCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_ImagingPlaneAlignmentMarkersCheckBoxValueChanged(event);
        end

        % Value changed function: 
        % VolumeDimensionsVoxelsLabelsCheckBox
        function VolumeDimensionsVoxelsLabelsCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_VolumeDimensionsVoxelsLabelsCheckBoxValueChanged(event);
        end

        % Value changed function: 
        % VolumeDimensionsMetricLabelsCheckBox
        function VolumeDimensionsMetricLabelsCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_VolumeDimensionsMetricLabelsCheckBoxValueChanged(event);
        end

        % Value changed function: VolumeCoordinateAxesLabelsCheckBox
        function VolumeCoordinateAxesLabelsCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_VolumeCoordinateAxesLabelsCheckBoxValueChanged(event);
        end

        % Value changed function: RepresentativeVoxelLabelsCheckBox
        function RepresentativeVoxelLabelsCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.ImagePopUp_RepresentativeVoxelLabelsCheckBoxValueChanged(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'Image Volume Viewer - Image Settings';
            app.ImageVolumeDisplaySettingsPanel = uipanel(app.MainFigure);
            app.ImageVolumeDisplaySettingsPanel.Title = 'Image Volume Display Settings:';
            app.ShowSliceIntersectionsCheckBox = uicheckbox(app.ImageVolumeDisplaySettingsPanel);
            app.ShowSliceIntersectionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowSliceIntersectionsCheckBoxValueChanged, true);
            app.ShowSliceIntersectionsCheckBox.Text = {'Show Slice'; 'Intersections'; ''};
            app.MinimumSpinnerLabel = uilabel(app.ImageVolumeDisplaySettingsPanel);
            app.MinimumSpinnerLabel.Text = 'Minimum:';
            app.DisplayMinimumSpinner = uispinner(app.ImageVolumeDisplaySettingsPanel);
            app.DisplayMinimumSpinner.ValueChangingFcn = createCallbackFcn(app, @DisplayMinimumSpinnerValueChanging, true);
            app.MaximumSpinnerLabel = uilabel(app.ImageVolumeDisplaySettingsPanel);
            app.MaximumSpinnerLabel.Text = 'Maximum:';
            app.DisplayMaximumSpinner = uispinner(app.ImageVolumeDisplaySettingsPanel);
            app.DisplayMaximumSpinner.ValueChangingFcn = createCallbackFcn(app, @DisplayMaximumSpinnerValueChanging, true);
            app.WindowSpinnerLabel = uilabel(app.ImageVolumeDisplaySettingsPanel);
            app.WindowSpinnerLabel.Text = 'Window:';
            app.DisplayWindowSpinner = uispinner(app.ImageVolumeDisplaySettingsPanel);
            app.DisplayWindowSpinner.ValueChangingFcn = createCallbackFcn(app, @DisplayWindowSpinnerValueChanging, true);
            app.LevelSpinnerLabel = uilabel(app.ImageVolumeDisplaySettingsPanel);
            app.LevelSpinnerLabel.Text = 'Level:';
            app.DisplayLevelSpinner = uispinner(app.ImageVolumeDisplaySettingsPanel);
            app.DisplayLevelSpinner.ValueChangingFcn = createCallbackFcn(app, @DisplayLevelSpinnerValueChanging, true);
            app.CloseButton = uibutton(app.MainFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DRenderDisplaySettingsPanel = uipanel(app.MainFigure);
            app.DRenderDisplaySettingsPanel.Title = '3D Render Display Settings:';
            app.CardinalAxesCheckBox = uicheckbox(app.DRenderDisplaySettingsPanel);
            app.CardinalAxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CardinalAxesCheckBoxValueChanged, true);
            app.CardinalAxesCheckBox.Text = 'Cardinal Axes';
            app.ImagingPlanesCheckBox = uicheckbox(app.DRenderDisplaySettingsPanel);
            app.ImagingPlanesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImagingPlanesCheckBoxValueChanged, true);
            app.ImagingPlanesCheckBox.Text = 'Imaging Planes';
            app.ImageVolumeBoundsCheckBox = uicheckbox(app.DRenderDisplaySettingsPanel);
            app.ImageVolumeBoundsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImageVolumeBoundsCheckBoxValueChanged, true);
            app.ImageVolumeBoundsCheckBox.Text = 'Image Volume Bounds';
            app.VolumeCoordinateAxesCheckBox = uicheckbox(app.DRenderDisplaySettingsPanel);
            app.VolumeCoordinateAxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @VolumeCoordinateAxesCheckBoxValueChanged, true);
            app.VolumeCoordinateAxesCheckBox.Text = 'Volume Coordinate Axes';
            app.RepresentativeVoxelCheckBox = uicheckbox(app.DRenderDisplaySettingsPanel);
            app.RepresentativeVoxelCheckBox.ValueChangedFcn = createCallbackFcn(app, @RepresentativeVoxelCheckBoxValueChanged, true);
            app.RepresentativeVoxelCheckBox.Text = 'Representative Voxel';
            app.RepresentativePatientCheckBox = uicheckbox(app.DRenderDisplaySettingsPanel);
            app.RepresentativePatientCheckBox.ValueChangedFcn = createCallbackFcn(app, @RepresentativePatientCheckBoxValueChanged, true);
            app.RepresentativePatientCheckBox.Text = 'Representative Patient';
            app.CardinalAxesLabelsCheckBox = uicheckbox(app.DRenderDisplaySettingsPanel);
            app.CardinalAxesLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CardinalAxesLabelsCheckBoxValueChanged, true);
            app.CardinalAxesLabelsCheckBox.Text = 'Cardinal Axes Labels';
            app.ImagingPlaneLabelsCheckBox = uicheckbox(app.DRenderDisplaySettingsPanel);
            app.ImagingPlaneLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImagingPlaneLabelsCheckBoxValueChanged, true);
            app.ImagingPlaneLabelsCheckBox.Text = 'Imaging Plane Labels';
            app.ImagingPlaneAlignmentMarkersCheckBox = uicheckbox(app.DRenderDisplaySettingsPanel);
            app.ImagingPlaneAlignmentMarkersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImagingPlaneAlignmentMarkersCheckBoxValueChanged, true);
            app.ImagingPlaneAlignmentMarkersCheckBox.Text = 'Imaging Plane Alignment Markers';
            app.VolumeDimensionsVoxelsLabelsCheckBox = uicheckbox(app.DRenderDisplaySettingsPanel);
            app.VolumeDimensionsVoxelsLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @VolumeDimensionsVoxelsLabelsCheckBoxValueChanged, true);
            app.VolumeDimensionsVoxelsLabelsCheckBox.Text = 'Volume Dimensions (num. voxels)';
            app.VolumeDimensionsMetricLabelsCheckBox = uicheckbox(app.DRenderDisplaySettingsPanel);
            app.VolumeDimensionsMetricLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @VolumeDimensionsMetricLabelsCheckBoxValueChanged, true);
            app.VolumeDimensionsMetricLabelsCheckBox.Text = 'Volume Dimensions (mm)';
            app.VolumeCoordinateAxesLabelsCheckBox = uicheckbox(app.DRenderDisplaySettingsPanel);
            app.VolumeCoordinateAxesLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @VolumeCoordinateAxesLabelsCheckBoxValueChanged, true);
            app.VolumeCoordinateAxesLabelsCheckBox.Text = 'Volume Coordinate Axes Labels';
            app.RepresentativeVoxelLabelsCheckBox = uicheckbox(app.DRenderDisplaySettingsPanel);
            app.RepresentativeVoxelLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RepresentativeVoxelLabelsCheckBoxValueChanged, true);
            app.RepresentativeVoxelLabelsCheckBox.Text = 'Representative Voxel Dimensions';
            app.LabelsCheckBox = uicheckbox(app.DRenderDisplaySettingsPanel);
            app.LabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LabelsCheckBoxValueChanged, true);
            app.LabelsCheckBox.Text = 'Labels';
            app.ImageHistogramUIAxes = uiaxes(app.MainFigure);
            title(app.ImageHistogramUIAxes, 'Image Histogram')
            xlabel(app.ImageHistogramUIAxes, 'Intensity Bins')
            ylabel(app.ImageHistogramUIAxes, 'Counts')
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageVolumeViewer_ImagePopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end