classdef ImageVolumeViewer_ControlsPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                     matlab.ui.Figure
        LockedControlsPanel            matlab.ui.container.Panel
        ChangeSliceLabel               matlab.ui.control.Label
        ScrollWheelLabel               matlab.ui.control.Label
        ZoomLabel                      matlab.ui.control.Label
        CtrlScrollWheelLabel           matlab.ui.control.Label
        PanLabel                       matlab.ui.control.Label
        RightClickandDragLabel         matlab.ui.control.Label
        ScrollWheelClickandDragLabel   matlab.ui.control.Label
        WindowLevelLabel               matlab.ui.control.Label
        LeftClickandDragLabel          matlab.ui.control.Label
        AllSliceSelectLabel            matlab.ui.control.Label
        CtrlLeftClickLabel             matlab.ui.control.Label
        CtrlRightClickLabel            matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
        AdjustableControlsPanel        matlab.ui.container.Panel
        ToggleROIsLabel                matlab.ui.control.Label
        CentreCurrentROILabel          matlab.ui.control.Label
        ToggleROIZoomLabel             matlab.ui.control.Label
        ToggleROIsHotkeyLabel          matlab.ui.control.Label
        AutoscrollROILabel             matlab.ui.control.Label
        AxialLabel                     matlab.ui.control.Label
        CoronalLabel                   matlab.ui.control.Label
        SagittalLabel                  matlab.ui.control.Label
        CentreCurrentROIHotkeyLabel    matlab.ui.control.Label
        ToggleROIZoomHotkeyLabel       matlab.ui.control.Label
        AutoscrollROISagittalHotkeyLabel  matlab.ui.control.Label
        AutoscrollROICoronalHotkeyLabel  matlab.ui.control.Label
        AutoscrollROIAxialHotkeyLabel  matlab.ui.control.Label
        ToggleROIHotkeySetButton       matlab.ui.control.Button
        CentreCurrentROIHotkeySetButton  matlab.ui.control.Button
        ToggleROIZoomHotkeySetButton   matlab.ui.control.Button
        AutoscrollROISagittalHotkeySetButton  matlab.ui.control.Button
        AutoscrollROICoronalHotkeySetButton  matlab.ui.control.Button
        AutoscrollROIAxialHotkeySetButton  matlab.ui.control.Button
        HotkeyPromptPanel              matlab.ui.container.Panel
        PressanykeytosethotkeyLabel    matlab.ui.control.Label
    end


    properties (Access = public)
        oImageVolumeViewerController = []
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, oImageVolumeViewerController)
            oImageVolumeViewerController.ControlsPopUp_startupFcn(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: ToggleROIHotkeySetButton
        function ToggleROIHotkeySetButtonPushed(app, event)
            app.oImageVolumeViewerController.ControlPopUp_HotkeySetButtonPushed(app, 'ToggleRegionsOfInterest');
        end

        % Button pushed function: CentreCurrentROIHotkeySetButton
        function CentreCurrentROIHotkeySetButtonPushed(app, event)
            app.oImageVolumeViewerController.ControlPopUp_HotkeySetButtonPushed(app, 'CentreCurrentRegionOfInterest');
        end

        % Button pushed function: ToggleROIZoomHotkeySetButton
        function ToggleROIZoomHotkeySetButtonPushed(app, event)
            app.oImageVolumeViewerController.ControlPopUp_HotkeySetButtonPushed(app, 'ToggleRegionOfInterestZoom');
        end

        % Button pushed function: 
        % AutoscrollROISagittalHotkeySetButton
        function AutoscrollROISagittalHotkeySetButtonPushed(app, event)
            app.oImageVolumeViewerController.ControlPopUp_HotkeySetButtonPushed(app, 'AutoscrollSagittal');
        end

        % Button pushed function: 
        % AutoscrollROICoronalHotkeySetButton
        function AutoscrollROICoronalHotkeySetButtonPushed(app, event)
            app.oImageVolumeViewerController.ControlPopUp_HotkeySetButtonPushed(app, 'AutoscrollCoronal');
        end

        % Button pushed function: AutoscrollROIAxialHotkeySetButton
        function AutoscrollROIAxialHotkeySetButtonPushed(app, event)
            app.oImageVolumeViewerController.ControlPopUp_HotkeySetButtonPushed(app, 'AutoscrollAxial');
        end

        % Window key press function: MainFigure
        function MainFigureWindowKeyPress(app, event)
            app.oImageVolumeViewerController.ControlPopUp_MainFigureWindowKeyPress(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'Image Volume Viewer - Control Settings';
            app.MainFigure.WindowKeyPressFcn = createCallbackFcn(app, @MainFigureWindowKeyPress, true);
            app.LockedControlsPanel = uipanel(app.MainFigure);
            app.LockedControlsPanel.Title = 'Locked Controls';
            app.ChangeSliceLabel = uilabel(app.LockedControlsPanel);
            app.ChangeSliceLabel.Text = 'Change Slice:';
            app.ScrollWheelLabel = uilabel(app.LockedControlsPanel);
            app.ScrollWheelLabel.Text = 'Scroll Wheel';
            app.ZoomLabel = uilabel(app.LockedControlsPanel);
            app.ZoomLabel.Text = 'Zoom:';
            app.CtrlScrollWheelLabel = uilabel(app.LockedControlsPanel);
            app.CtrlScrollWheelLabel.Text = 'Ctrl + Scroll Wheel';
            app.PanLabel = uilabel(app.LockedControlsPanel);
            app.PanLabel.Text = 'Pan:';
            app.RightClickandDragLabel = uilabel(app.LockedControlsPanel);
            app.RightClickandDragLabel.Text = 'Right Click-and-Drag';
            app.ScrollWheelClickandDragLabel = uilabel(app.LockedControlsPanel);
            app.ScrollWheelClickandDragLabel.Text = 'Scroll Wheel Click-and-Drag';
            app.WindowLevelLabel = uilabel(app.LockedControlsPanel);
            app.WindowLevelLabel.Text = 'Window/Level:';
            app.LeftClickandDragLabel = uilabel(app.LockedControlsPanel);
            app.LeftClickandDragLabel.Text = 'Left Click-and-Drag';
            app.AllSliceSelectLabel = uilabel(app.LockedControlsPanel);
            app.AllSliceSelectLabel.Text = 'All Slice Select:';
            app.CtrlLeftClickLabel = uilabel(app.LockedControlsPanel);
            app.CtrlLeftClickLabel.Text = 'Ctrl + Left Click';
            app.CtrlRightClickLabel = uilabel(app.LockedControlsPanel);
            app.CtrlRightClickLabel.Text = 'Ctrl + Right Click';
            app.CloseButton = uibutton(app.MainFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.AdjustableControlsPanel = uipanel(app.MainFigure);
            app.AdjustableControlsPanel.Title = 'Adjustable Controls';
            app.ToggleROIsLabel = uilabel(app.AdjustableControlsPanel);
            app.ToggleROIsLabel.Text = 'Toggle ROIs:';
            app.CentreCurrentROILabel = uilabel(app.AdjustableControlsPanel);
            app.CentreCurrentROILabel.Text = 'Centre Current ROI:';
            app.ToggleROIZoomLabel = uilabel(app.AdjustableControlsPanel);
            app.ToggleROIZoomLabel.Text = 'Toggle ROI Zoom:';
            app.ToggleROIsHotkeyLabel = uilabel(app.AdjustableControlsPanel);
            app.ToggleROIsHotkeyLabel.Text = 's';
            app.AutoscrollROILabel = uilabel(app.AdjustableControlsPanel);
            app.AutoscrollROILabel.Text = 'Autoscroll ROI:';
            app.AxialLabel = uilabel(app.AdjustableControlsPanel);
            app.AxialLabel.Text = 'Axial:';
            app.CoronalLabel = uilabel(app.AdjustableControlsPanel);
            app.CoronalLabel.Text = 'Coronal:';
            app.SagittalLabel = uilabel(app.AdjustableControlsPanel);
            app.SagittalLabel.Text = 'Sagittal:';
            app.CentreCurrentROIHotkeyLabel = uilabel(app.AdjustableControlsPanel);
            app.CentreCurrentROIHotkeyLabel.Text = 'c';
            app.ToggleROIZoomHotkeyLabel = uilabel(app.AdjustableControlsPanel);
            app.ToggleROIZoomHotkeyLabel.Text = 'z';
            app.AutoscrollROISagittalHotkeyLabel = uilabel(app.AdjustableControlsPanel);
            app.AutoscrollROISagittalHotkeyLabel.Text = '1';
            app.AutoscrollROICoronalHotkeyLabel = uilabel(app.AdjustableControlsPanel);
            app.AutoscrollROICoronalHotkeyLabel.Text = '2';
            app.AutoscrollROIAxialHotkeyLabel = uilabel(app.AdjustableControlsPanel);
            app.AutoscrollROIAxialHotkeyLabel.Text = '3';
            app.ToggleROIHotkeySetButton = uibutton(app.AdjustableControlsPanel, 'push');
            app.ToggleROIHotkeySetButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleROIHotkeySetButtonPushed, true);
            app.ToggleROIHotkeySetButton.Text = 'Set...';
            app.CentreCurrentROIHotkeySetButton = uibutton(app.AdjustableControlsPanel, 'push');
            app.CentreCurrentROIHotkeySetButton.ButtonPushedFcn = createCallbackFcn(app, @CentreCurrentROIHotkeySetButtonPushed, true);
            app.CentreCurrentROIHotkeySetButton.Text = 'Set...';
            app.ToggleROIZoomHotkeySetButton = uibutton(app.AdjustableControlsPanel, 'push');
            app.ToggleROIZoomHotkeySetButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleROIZoomHotkeySetButtonPushed, true);
            app.ToggleROIZoomHotkeySetButton.Text = 'Set...';
            app.AutoscrollROISagittalHotkeySetButton = uibutton(app.AdjustableControlsPanel, 'push');
            app.AutoscrollROISagittalHotkeySetButton.ButtonPushedFcn = createCallbackFcn(app, @AutoscrollROISagittalHotkeySetButtonPushed, true);
            app.AutoscrollROISagittalHotkeySetButton.Text = 'Set...';
            app.AutoscrollROICoronalHotkeySetButton = uibutton(app.AdjustableControlsPanel, 'push');
            app.AutoscrollROICoronalHotkeySetButton.ButtonPushedFcn = createCallbackFcn(app, @AutoscrollROICoronalHotkeySetButtonPushed, true);
            app.AutoscrollROICoronalHotkeySetButton.Text = 'Set...';
            app.AutoscrollROIAxialHotkeySetButton = uibutton(app.AdjustableControlsPanel, 'push');
            app.AutoscrollROIAxialHotkeySetButton.ButtonPushedFcn = createCallbackFcn(app, @AutoscrollROIAxialHotkeySetButtonPushed, true);
            app.AutoscrollROIAxialHotkeySetButton.Text = 'Set...';
            app.HotkeyPromptPanel = uipanel(app.MainFigure);
            app.PressanykeytosethotkeyLabel = uilabel(app.HotkeyPromptPanel);
            app.PressanykeytosethotkeyLabel.Text = 'Press any key to set hotkey.';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageVolumeViewer_ControlsPopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end