classdef ImageVolumeViewer_RegionsOfInterestPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                      matlab.ui.Figure
        AvailableRegionsofInterestPanel  matlab.ui.container.Panel
        SelectionUITable                matlab.ui.control.Table
        RegionsofInterestDisplaySettingsPanel  matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        OverlayTransparencySliderLabel  matlab.ui.control.Label
        OverlayTransparencySlider       matlab.ui.control.Slider
        LineWidthSliderLabel            matlab.ui.control.Label
        LineWidthSlider                 matlab.ui.control.Slider
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        OriginalContourPolygonsPanel    matlab.ui.container.Panel
        ContourPolygonsVoxelOverlayCheckBox  matlab.ui.control.CheckBox
        ContourPolygonsBorderCheckBox   matlab.ui.control.CheckBox
        ContourPolygonsVerticesCheckBox  matlab.ui.control.CheckBox
        ContourPolygonsShowDisabledPolygonsCheckBox  matlab.ui.control.CheckBox
        ContourPolygonsShowIn3DRenderCheckBox  matlab.ui.control.CheckBox
        VoxelMaskPanel                  matlab.ui.container.Panel
        VoxelOverlayCheckBox            matlab.ui.control.CheckBox
        BorderCheckBox                  matlab.ui.control.CheckBox
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        DRenderTransparencySliderLabel  matlab.ui.control.Label
        Render3DTransparencySlider      matlab.ui.control.Slider
        Render3DEdgesCheckBox           matlab.ui.control.CheckBox
        Render3DShadowsCheckBox         matlab.ui.control.CheckBox
        Render3DMeshesCheckBox          matlab.ui.control.CheckBox
        CloseButton                     matlab.ui.control.Button
    end


    properties (Access = public)
        oImageVolumeViewerController = []
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, oImageVolumeViewerController)
            oImageVolumeViewerController.RegionsOfInterestPopUp_startupFcn(app);
        end

        % Cell edit callback: SelectionUITable
        function SelectionUITableCellEdit(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_SelectionUITableCellEdit(event);
        end

        % Cell selection callback: SelectionUITable
        function SelectionUITableCellSelection(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_SelectionUITableCellSelection(event);
        end

        % Callback function
        function LineWidthSliderValueChanged(app, event)
            app.oInteractiveDicomViewerController.RegionsOfInterestPopUp_LineWidthSliderValueChanging(event);
        end

        % Value changing function: OverlayTransparencySlider
        function OverlayTransparencySliderValueChanging(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_OverlayTransparencySliderValueChanging(event);
        end

        % Value changing function: LineWidthSlider
        function LineWidthSliderValueChanging(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_LineWidthSliderValueChanging(event);
        end

        % Value changed function: VoxelOverlayCheckBox
        function VoxelOverlayCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_VoxelOverlayCheckBoxValueChanged(event);
        end

        % Value changed function: BorderCheckBox
        function BorderCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_BorderCheckBoxValueChanged(event);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changing function: Render3DTransparencySlider
        function Render3DTransparencySliderValueChanging(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_Render3DTransparencySliderValueChanging(event);
        end

        % Value changed function: Render3DEdgesCheckBox
        function Render3DEdgesCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_Render3DEdgesCheckBoxValueChanged(event);
        end

        % Value changed function: Render3DShadowsCheckBox
        function Render3DShadowsCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_Render3DShadowsCheckBoxValueChanged(event);
        end

        % Value changed function: 
        % ContourPolygonsVoxelOverlayCheckBox
        function ContourPolygonsVoxelOverlayCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_PolygonsVoxelOverlayCheckBoxValueChanged(event);            
        end

        % Value changed function: ContourPolygonsBorderCheckBox
        function ContourPolygonsBorderCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_PolygonsBorderCheckBoxValueChanged(event);
        end

        % Value changed function: ContourPolygonsVerticesCheckBox
        function ContourPolygonsVerticesCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_PolygonsVerticesCheckBoxValueChanged(event);
        end

        % Value changed function: 
        % ContourPolygonsShowDisabledPolygonsCheckBox
        function ContourPolygonsShowDisabledPolygonsCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_ShowDisabledPolygonsCheckBoxValueChanged(event);
        end

        % Value changed function: 
        % ContourPolygonsShowIn3DRenderCheckBox
        function ContourPolygonsShowIn3DRenderCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_ShowPolygons3DRenderCheckBoxValueChanged(event);
        end

        % Value changed function: Render3DMeshesCheckBox
        function Render3DMeshesCheckBoxValueChanged(app, event)
            app.oImageVolumeViewerController.RegionsOfInterestPopUp_3DRenderMeshesCheckBoxValueChanged(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'Image Volume Viewer - Regions of Interest Settings';
            app.AvailableRegionsofInterestPanel = uipanel(app.MainFigure);
            app.AvailableRegionsofInterestPanel.Title = 'Available Regions of Interest:';
            app.SelectionUITable = uitable(app.AvailableRegionsofInterestPanel);
            app.SelectionUITable.ColumnName = {'Show'; 'Centred'; 'Contour'; 'Colour [RGB]'; 'ROI Name'; 'Obs. Label'; 'Inter. Type'};
            app.SelectionUITable.RowName = {};
            app.SelectionUITable.ColumnEditable = [true true false false false false];
            app.SelectionUITable.CellEditCallback = createCallbackFcn(app, @SelectionUITableCellEdit, true);
            app.SelectionUITable.CellSelectionCallback = createCallbackFcn(app, @SelectionUITableCellSelection, true);
            app.RegionsofInterestDisplaySettingsPanel = uipanel(app.MainFigure);
            app.RegionsofInterestDisplaySettingsPanel.Title = 'Regions of Interest Display Settings:';
            app.Label = uilabel(app.RegionsofInterestDisplaySettingsPanel);
            app.Label.Text = '0%';
            app.Label_2 = uilabel(app.RegionsofInterestDisplaySettingsPanel);
            app.Label_2.Text = '50%';
            app.Label_3 = uilabel(app.RegionsofInterestDisplaySettingsPanel);
            app.Label_3.Text = '100%';
            app.OverlayTransparencySliderLabel = uilabel(app.RegionsofInterestDisplaySettingsPanel);
            app.OverlayTransparencySliderLabel.Text = 'Overlay Transparency:';
            app.OverlayTransparencySlider = uislider(app.RegionsofInterestDisplaySettingsPanel);
            app.OverlayTransparencySlider.Limits = [0 1];
            app.OverlayTransparencySlider.MajorTicks = [];
            app.OverlayTransparencySlider.ValueChangingFcn = createCallbackFcn(app, @OverlayTransparencySliderValueChanging, true);
            app.OverlayTransparencySlider.MinorTicks = [];
            app.OverlayTransparencySlider.Value = 0.5;
            app.LineWidthSliderLabel = uilabel(app.RegionsofInterestDisplaySettingsPanel);
            app.LineWidthSliderLabel.Text = 'Line Width:';
            app.LineWidthSlider = uislider(app.RegionsofInterestDisplaySettingsPanel);
            app.LineWidthSlider.Limits = [0.001 10];
            app.LineWidthSlider.MajorTicks = [];
            app.LineWidthSlider.ValueChangingFcn = createCallbackFcn(app, @LineWidthSliderValueChanging, true);
            app.LineWidthSlider.MinorTicks = [];
            app.LineWidthSlider.Value = 2;
            app.Label_4 = uilabel(app.RegionsofInterestDisplaySettingsPanel);
            app.Label_4.Text = '0';
            app.Label_5 = uilabel(app.RegionsofInterestDisplaySettingsPanel);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.RegionsofInterestDisplaySettingsPanel);
            app.Label_6.Text = '10';
            app.OriginalContourPolygonsPanel = uipanel(app.RegionsofInterestDisplaySettingsPanel);
            app.OriginalContourPolygonsPanel.Title = 'Original Contour Polygons';
            app.ContourPolygonsVoxelOverlayCheckBox = uicheckbox(app.OriginalContourPolygonsPanel);
            app.ContourPolygonsVoxelOverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContourPolygonsVoxelOverlayCheckBoxValueChanged, true);
            app.ContourPolygonsVoxelOverlayCheckBox.Enable = 'off';
            app.ContourPolygonsVoxelOverlayCheckBox.Text = 'Voxel Overlay';
            app.ContourPolygonsBorderCheckBox = uicheckbox(app.OriginalContourPolygonsPanel);
            app.ContourPolygonsBorderCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContourPolygonsBorderCheckBoxValueChanged, true);
            app.ContourPolygonsBorderCheckBox.Enable = 'off';
            app.ContourPolygonsBorderCheckBox.Text = 'Border';
            app.ContourPolygonsVerticesCheckBox = uicheckbox(app.OriginalContourPolygonsPanel);
            app.ContourPolygonsVerticesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContourPolygonsVerticesCheckBoxValueChanged, true);
            app.ContourPolygonsVerticesCheckBox.Enable = 'off';
            app.ContourPolygonsVerticesCheckBox.Text = 'Vertices';
            app.ContourPolygonsShowDisabledPolygonsCheckBox = uicheckbox(app.OriginalContourPolygonsPanel);
            app.ContourPolygonsShowDisabledPolygonsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContourPolygonsShowDisabledPolygonsCheckBoxValueChanged, true);
            app.ContourPolygonsShowDisabledPolygonsCheckBox.Enable = 'off';
            app.ContourPolygonsShowDisabledPolygonsCheckBox.Text = 'Show Disabled Polygons';
            app.ContourPolygonsShowIn3DRenderCheckBox = uicheckbox(app.OriginalContourPolygonsPanel);
            app.ContourPolygonsShowIn3DRenderCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContourPolygonsShowIn3DRenderCheckBoxValueChanged, true);
            app.ContourPolygonsShowIn3DRenderCheckBox.Enable = 'off';
            app.ContourPolygonsShowIn3DRenderCheckBox.Text = 'Show in 3D Render';
            app.VoxelMaskPanel = uipanel(app.RegionsofInterestDisplaySettingsPanel);
            app.VoxelMaskPanel.Title = 'Voxel Mask';
            app.VoxelOverlayCheckBox = uicheckbox(app.VoxelMaskPanel);
            app.VoxelOverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @VoxelOverlayCheckBoxValueChanged, true);
            app.VoxelOverlayCheckBox.Text = 'Voxel Overlay';
            app.BorderCheckBox = uicheckbox(app.VoxelMaskPanel);
            app.BorderCheckBox.ValueChangedFcn = createCallbackFcn(app, @BorderCheckBoxValueChanged, true);
            app.BorderCheckBox.Text = 'Border';
            app.Label_7 = uilabel(app.RegionsofInterestDisplaySettingsPanel);
            app.Label_7.Text = '0%';
            app.Label_8 = uilabel(app.RegionsofInterestDisplaySettingsPanel);
            app.Label_8.Text = '50%';
            app.Label_9 = uilabel(app.RegionsofInterestDisplaySettingsPanel);
            app.Label_9.Text = '100%';
            app.DRenderTransparencySliderLabel = uilabel(app.RegionsofInterestDisplaySettingsPanel);
            app.DRenderTransparencySliderLabel.Text = '3D Render Transparency:';
            app.Render3DTransparencySlider = uislider(app.RegionsofInterestDisplaySettingsPanel);
            app.Render3DTransparencySlider.Limits = [0 1];
            app.Render3DTransparencySlider.MajorTicks = [];
            app.Render3DTransparencySlider.ValueChangingFcn = createCallbackFcn(app, @Render3DTransparencySliderValueChanging, true);
            app.Render3DTransparencySlider.MinorTicks = [];
            app.Render3DTransparencySlider.Value = 0.5;
            app.Render3DEdgesCheckBox = uicheckbox(app.RegionsofInterestDisplaySettingsPanel);
            app.Render3DEdgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @Render3DEdgesCheckBoxValueChanged, true);
            app.Render3DEdgesCheckBox.Text = '3D Render Edges';
            app.Render3DShadowsCheckBox = uicheckbox(app.RegionsofInterestDisplaySettingsPanel);
            app.Render3DShadowsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Render3DShadowsCheckBoxValueChanged, true);
            app.Render3DShadowsCheckBox.Text = '3D Render Shadows';
            app.Render3DMeshesCheckBox = uicheckbox(app.RegionsofInterestDisplaySettingsPanel);
            app.Render3DMeshesCheckBox.ValueChangedFcn = createCallbackFcn(app, @Render3DMeshesCheckBoxValueChanged, true);
            app.Render3DMeshesCheckBox.Text = '3D Render Meshes';
            app.CloseButton = uibutton(app.MainFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageVolumeViewer_RegionsOfInterestPopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end