classdef ImageVolumeViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                    matlab.ui.Figure
        AxialPanel                    matlab.ui.container.Panel
        AxialPanel_MaximizeButton     matlab.ui.control.Button
        AxialPanel_SliceSlider        matlab.ui.control.Slider
        AxialPanel_SliceSpinner       matlab.ui.control.Spinner
        CoronalPanel                  matlab.ui.container.Panel
        CoronalPanel_MaximizeButton   matlab.ui.control.Button
        CoronalPanel_SliceSlider      matlab.ui.control.Slider
        CoronalPanel_SliceSpinner     matlab.ui.control.Spinner
        CoronalPlane                  matlab.ui.control.UIAxes
        AxialPlane                    matlab.ui.control.UIAxes
        SagittalPanel                 matlab.ui.container.Panel
        SagittalPanel_MaximizeButton  matlab.ui.control.Button
        SagittalPanel_SliceSlider     matlab.ui.control.Slider
        SagittalPanel_SliceSpinner    matlab.ui.control.Spinner
        SagittalPlane                 matlab.ui.control.UIAxes
        GeometryButton                matlab.ui.control.Button
        ROIButton                     matlab.ui.control.Button
        Render3DPanel                 matlab.ui.container.Panel
        Render3DPanel_MaximizeButton  matlab.ui.control.Button
        Render3DAxes                  matlab.ui.control.UIAxes
        ControlsButton                matlab.ui.control.Button
        ImageButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
    end
    
    properties (SetAccess = ?ImageVolumeViewerController, GetAccess = {?ImageVolumeViewerController, ?ImageVolumeViewerTask})
        oImageVolumeViewerController = []
    end
    
    methods (Access = public)
        
        function SetNewImageVolume(app, oImageVolume)
            app.oImageVolumeViewerController.SetNewImageVolume(oImageVolume);
        end
        
        function SetAppVisibility(app, chVisible)
            app.oImageVolumeViewerController.SetAppVisibility(chVisible);            
        end
        
        function oImageVolumeViewRecord = GetCurrentImageVolumeView(app)
            oImageVolumeViewRecord = app.oImageVolumeViewerController.GetCurrentImageVolumeView();
        end
        
        function SetCurrentImageVolumeView(app, oImageVolumeViewRecord)
            app.oImageVolumeViewerController.SetCurrentImageVolumeView(oImageVolumeViewRecord);
        end
        
        function SetTask(app, oImageVolumeViewerTask)
            app.oImageVolumeViewerController.SetTask(oImageVolumeViewerTask);            
        end
        
        function CentreOnRegionOfInterest(app, dRegionOfInterestNumber)
            app.oImageVolumeViewerController.CentreOnRegionOfInterest(dRegionOfInterestNumber);
        end
        
        function SetRegionOfInterestVisibility(app, dRegionOfInterestNumber, bVisible)
            app.oImageVolumeViewerController.SetRegionOfInterestVisibility(dRegionOfInterestNumber, bVisible);
        end
        
        function SetRegionOfInterestColour(app, dRegionOfInterestNumber, vdColour_rgb)
            app.oImageVolumeViewerController.SetRegionOfInterestColour(dRegionOfInterestNumber, vdColour_rgb);
        end
        
        function SetRegionOfInterestVoxelMaskOverlayVisibility(app, bVisible)
            app.oImageVolumeViewerController.SetRegionOfInterestVoxelMaskOverlayVisibility(bVisible);
        end
        
        function SetRegionOfInterestVoxelMaskOutlineLineWidth(app, dLineWidth)
            app.oImageVolumeViewerController.SetRegionOfInterestVoxelMaskOutlineLineWidth(dLineWidth);
        end
        
        function SetImageDataDisplayThreshold(app, vdImageDataDisplayThreshold)
            app.oImageVolumeViewerController.SetImageDataDisplayThreshold(vdImageDataDisplayThreshold);            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, oImageVolume)
            arguments
               app
               oImageVolume = MATLABImageVolume.empty
            end
            
            ImageVolumeViewerController.startupFcn(app, oImageVolume);
        end

        % Key press function: MainFigure
        function MainFigureKeyPress(app, event)
            if ~isempty(app.oImageVolumeViewerController)
                app.oImageVolumeViewerController.MainFigureKeyPress(event);
            end
        end

        % Window scroll wheel function: MainFigure
        function MainFigureWindowScrollWheel(app, event)
            if ~isempty(app.oImageVolumeViewerController)
                app.oImageVolumeViewerController.MainFigureWindowScrollWheel(event);
            end
        end

        % Key release function: MainFigure
        function MainFigureKeyRelease(app, event)
            if ~isempty(app.oImageVolumeViewerController)
                app.oImageVolumeViewerController.MainFigureKeyRelease(event);
            end
        end

        % Window button motion function: MainFigure
        function MainFigureWindowButtonMotion(app, event)
            if ~isempty(app.oImageVolumeViewerController)
                app.oImageVolumeViewerController.MainFigureWindowButtonMotion();
            end
        end

        % Window button up function: MainFigure
        function MainFigureWindowButtonUp(app, event)
            if ~isempty(app.oImageVolumeViewerController)
                app.oImageVolumeViewerController.MainFigureWindowButtonUp();
            end
        end

        % Window button down function: MainFigure
        function MainFigureWindowButtonDown(app, event)
            if ~isempty(app.oImageVolumeViewerController)
                app.oImageVolumeViewerController.MainFigureWindowButtonDown(event);
            end
        end

        % Callback function
        function MaximizeInNewWindowButton_AxialPushed(app, event)
            BasicDicomViewer_MaximizeInNewWindowButtonCallback(app, app.oInteractiveDicomViewerController.c1oInteractiveImagingPlanes{3});
        end

        % Callback function
        function MaximizeInNewWindowButton_SagittalPushed(app, event)
            BasicDicomViewer_MaximizeInNewWindowButtonCallback(app, app.oInteractiveDicomViewerController.c1oInteractiveImagingPlanes{1});
        end

        % Callback function
        function MaximizeInNewWindowButton_CoronalPushed(app, event)
            BasicDicomViewer_MaximizeInNewWindowButtonCallback(app, app.oInteractiveDicomViewerController.c1oInteractiveImagingPlanes{2});
        end

        % Size changed function: MainFigure
        function MainFigureSizeChanged(app, event)
            if ~isempty(app.oImageVolumeViewerController)
                app.oImageVolumeViewerController.MainFigureSizeChanged(app);
            end
        end

        % Callback function
        function Render3DPanel_Rotate3DButtonValueChanged(app, event)
            %             app.fnCachedWindowButtonMotionCallback = app.MainFigure.WindowButtonMotionFcn;
            %             app.fnCachedWindowButtonDownCallback = app.MainFigure.WindowButtonDownFcn;
            %             app.fnCachedWindowButtonUpCallback = app.MainFigure.WindowButtonUpFcn;
            %
            %             app.MainFigure.WindowButtonMotionFcn = [];
            %             app.MainFigure.WindowButtonDownFcn = [];
            %             app.MainFigure.WindowButtonUpFcn = [];
            %             drawnow;
            %             rotate3d(app.Render3DAxes, 'on');
            
            %app.oImageVolumeViewerController.Render3DPanel_Rotate3DButtonValueChanged(app);
        end

        % Value changing function: AxialPanel_SliceSlider
        function AxialPanel_SliceSliderValueChanging(app, event)
            app.oImageVolumeViewerController.AxialSliceSliderValueChanging(event);
        end

        % Value changing function: CoronalPanel_SliceSlider
        function CoronalPanel_SliceSliderValueChanging(app, event)
            app.oImageVolumeViewerController.CoronalSliceSliderValueChanging(event);
        end

        % Value changing function: SagittalPanel_SliceSlider
        function SagittalPanel_SliceSliderValueChanging(app, event)
            app.oImageVolumeViewerController.SagittalSliceSliderValueChanging(event);
        end

        % Value changed function: AxialPanel_SliceSpinner
        function AxialPanel_SliceSpinnerValueChanged(app, event)
            app.oImageVolumeViewerController.AxialSliceSpinnerValueChanged(event);
        end

        % Value changed function: CoronalPanel_SliceSpinner
        function CoronalPanel_SliceSpinnerValueChanged(app, event)
            app.oImageVolumeViewerController.CoronalSliceSpinnerValueChanged(event);
        end

        % Value changed function: SagittalPanel_SliceSpinner
        function SagittalPanel_SliceSpinnerValueChanged(app, event)
            app.oImageVolumeViewerController.SagittalSliceSpinnerValueChanged(event);
        end

        % Button pushed function: GeometryButton
        function GeometryButtonPushed(app, event)
            app.oImageVolumeViewerController.GeometryButtonPushed();
        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)
            app.oImageVolumeViewerController.ROIButtonPushed();
        end

        % Callback function
        function Render3DPanel_ZoomInButtonPushed(app, event)
            app.oImageVolumeViewerController.Render3DPanel_ZoomInButtonPushed();
        end

        % Callback function
        function Render3DPanel_ZoomOutButtonPushed(app, event)
            app.oImageVolumeViewerController.Render3DPanel_ZoomOutButtonPushed();
        end

        % Button pushed function: Render3DPanel_MaximizeButton
        function Render3DPanel_MaximizeButtonPushed(app, event)
            app.oImageVolumeViewerController.Render3DPanel_MaximizeButtonPushed();
        end

        % Button pushed function: ControlsButton
        function ControlsButtonPushed(app, event)
            app.oImageVolumeViewerController.ControlsButtonPushed();
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            app.oImageVolumeViewerController.ImageButtonPushed();
        end

        % Close request function: MainFigure
        function MainFigureCloseRequest(app, event)
            app.oImageVolumeViewerController.MainFigureCloseRequest(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'Image Volume Viewer';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @MainFigureCloseRequest, true);
            app.MainFigure.SizeChangedFcn = createCallbackFcn(app, @MainFigureSizeChanged, true);
            app.MainFigure.WindowButtonDownFcn = createCallbackFcn(app, @MainFigureWindowButtonDown, true);
            app.MainFigure.WindowButtonUpFcn = createCallbackFcn(app, @MainFigureWindowButtonUp, true);
            app.MainFigure.WindowButtonMotionFcn = createCallbackFcn(app, @MainFigureWindowButtonMotion, true);
            app.MainFigure.WindowScrollWheelFcn = createCallbackFcn(app, @MainFigureWindowScrollWheel, true);
            app.MainFigure.KeyPressFcn = createCallbackFcn(app, @MainFigureKeyPress, true);
            app.MainFigure.KeyReleaseFcn = createCallbackFcn(app, @MainFigureKeyRelease, true);
            app.AxialPanel = uipanel(app.MainFigure);
            app.AxialPanel.Title = 'Panel';
            app.AxialPanel_MaximizeButton = uibutton(app.AxialPanel, 'push');
            app.AxialPanel_MaximizeButton.Enable = 'off';
            app.AxialPanel_MaximizeButton.Text = '+';
            app.AxialPanel_SliceSlider = uislider(app.AxialPanel);
            app.AxialPanel_SliceSlider.MajorTicks = [];
            app.AxialPanel_SliceSlider.ValueChangingFcn = createCallbackFcn(app, @AxialPanel_SliceSliderValueChanging, true);
            app.AxialPanel_SliceSlider.MinorTicks = [];
            app.AxialPanel_SliceSpinner = uispinner(app.AxialPanel);
            app.AxialPanel_SliceSpinner.Limits = [1 Inf];
            app.AxialPanel_SliceSpinner.RoundFractionalValues = 'on';
            app.AxialPanel_SliceSpinner.ValueDisplayFormat = '%.0f';
            app.AxialPanel_SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @AxialPanel_SliceSpinnerValueChanged, true);
            app.AxialPanel_SliceSpinner.Value = 1;
            app.CoronalPanel = uipanel(app.MainFigure);
            app.CoronalPanel.Title = 'Panel';
            app.CoronalPanel_MaximizeButton = uibutton(app.CoronalPanel, 'push');
            app.CoronalPanel_MaximizeButton.Enable = 'off';
            app.CoronalPanel_MaximizeButton.Text = '+';
            app.CoronalPanel_SliceSlider = uislider(app.CoronalPanel);
            app.CoronalPanel_SliceSlider.MajorTicks = [];
            app.CoronalPanel_SliceSlider.ValueChangingFcn = createCallbackFcn(app, @CoronalPanel_SliceSliderValueChanging, true);
            app.CoronalPanel_SliceSlider.MinorTicks = [];
            app.CoronalPanel_SliceSpinner = uispinner(app.CoronalPanel);
            app.CoronalPanel_SliceSpinner.Limits = [1 Inf];
            app.CoronalPanel_SliceSpinner.RoundFractionalValues = 'on';
            app.CoronalPanel_SliceSpinner.ValueDisplayFormat = '%.0f';
            app.CoronalPanel_SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @CoronalPanel_SliceSpinnerValueChanged, true);
            app.CoronalPanel_SliceSpinner.Value = 1;
            app.CoronalPlane = uiaxes(app.MainFigure);
            app.CoronalPlane.XTick = [];
            app.CoronalPlane.YTick = [];
            app.AxialPlane = uiaxes(app.MainFigure);
            app.AxialPlane.XTick = [];
            app.AxialPlane.YTick = [];
            app.SagittalPanel = uipanel(app.MainFigure);
            app.SagittalPanel.Title = 'Panel';
            app.SagittalPanel_MaximizeButton = uibutton(app.SagittalPanel, 'push');
            app.SagittalPanel_MaximizeButton.Enable = 'off';
            app.SagittalPanel_MaximizeButton.Text = '+';
            app.SagittalPanel_SliceSlider = uislider(app.SagittalPanel);
            app.SagittalPanel_SliceSlider.MajorTicks = [];
            app.SagittalPanel_SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SagittalPanel_SliceSliderValueChanging, true);
            app.SagittalPanel_SliceSlider.MinorTicks = [];
            app.SagittalPanel_SliceSpinner = uispinner(app.SagittalPanel);
            app.SagittalPanel_SliceSpinner.Limits = [1 Inf];
            app.SagittalPanel_SliceSpinner.RoundFractionalValues = 'on';
            app.SagittalPanel_SliceSpinner.ValueDisplayFormat = '%.0f';
            app.SagittalPanel_SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @SagittalPanel_SliceSpinnerValueChanged, true);
            app.SagittalPanel_SliceSpinner.Value = 1;
            app.SagittalPlane = uiaxes(app.MainFigure);
            app.SagittalPlane.XTick = [];
            app.SagittalPlane.YTick = [];
            app.GeometryButton = uibutton(app.MainFigure, 'push');
            app.GeometryButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryButtonPushed, true);
            app.GeometryButton.Text = 'Geometry';
            app.ROIButton = uibutton(app.MainFigure, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Text = 'ROI';
            app.Render3DPanel = uipanel(app.MainFigure);
            app.Render3DPanel.Title = 'Panel';
            app.Render3DPanel_MaximizeButton = uibutton(app.Render3DPanel, 'push');
            app.Render3DPanel_MaximizeButton.ButtonPushedFcn = createCallbackFcn(app, @Render3DPanel_MaximizeButtonPushed, true);
            app.Render3DPanel_MaximizeButton.Text = '+';
            app.Render3DAxes = uiaxes(app.MainFigure);
            app.Render3DAxes.XTick = [];
            app.Render3DAxes.YTick = [];
            app.ControlsButton = uibutton(app.MainFigure, 'push');
            app.ControlsButton.ButtonPushedFcn = createCallbackFcn(app, @ControlsButtonPushed, true);
            app.ControlsButton.Text = 'Controls';
            app.ImageButton = uibutton(app.MainFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageVolumeViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end